classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        TheoPlot   matlab.ui.control.Button
        KLabel     matlab.ui.control.Label
        ExpPlot    matlab.ui.control.Button
        EditField  matlab.ui.control.EditField
        Label      matlab.ui.control.Label
        UIAxes     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            
        end

        % Button pushed function: ExpPlot
        function ExpPlotPushed(app, event)
            %理论曲线
            value = app.EditField.Value;%输入温度
            
            %实验数据文件名
            File="Data\"+["E2270K.txt" "E2330K.txt" "E2360K.txt" ...
            "E2400K.txt" "E2440K.txt" "E2500K.txt" "E2580K.txt"...
            "E2660K.txt" "E2750K.txt" "E2860K.txt" "E2940K.txt"];
            %寻找输入温度的实验数据
            for i=1:11
                s1="Data\E"+value+"K.txt";
                s2=File(i);
                if strcmp(s1,s2)==1
                    file_n=i;
                end
            end
            %载入第ffile_n个实验数据  
            Lab_Data=load(File(file_n));
            lambda=Lab_Data(:,1);%横坐标
            E_e=Lab_Data(:,2);%纵坐标

            %动态实验曲线绘制
            cla(app.UIAxes);%清理曲线

            %图窗参数
            axis(app.UIAxes,[800,2500,0,6000]);%坐标范围
            grid(app.UIAxes,"on");%网格
            title(app.UIAxes,"");%图像标题
            xlabel(app.UIAxes,"波长\lambda(nm)");%横坐标名
            ylabel(app.UIAxes,"辐射密度E(\lambda,T)(W/mm^3)");%纵坐标名

            for j=2:length(lambda)
                hold(app.UIAxes,"on");
                i=j-1;
                plot(app.UIAxes,lambda(i:j),E_e(i:j),'-g');
                pause(0.0001);
            end
        end

        % Button pushed function: TheoPlot
        function TheoPlotButtonPushed(app, event)
            %理论曲线
            value = app.EditField.Value;%输入温度
            T=str2double(value);%将输入温度的形式从字符转化为数值

            %物理常数
            h=6.62607015*10^-34;
            c=299792458;
            k=1.380649*10^-23;
            %经验参数
            c1=2*pi*h*c^2;
            c2=h*c/k;

            %自变量
            lambda=800:1:2500;%单位nm
            Lambda=lambda*10^-9;%单位m
            %计算辐射密度理论曲线
            E_t=(c1./power(Lambda,5)).*(1./(exp(c2./(Lambda.*T))-1))*10^-9;

            %设置图窗参数
            hold(app.UIAxes,"on");
            axis(app.UIAxes,[800,2500,0,6000]);%坐标范围
            grid(app.UIAxes,"on");%网格
            title(app.UIAxes,"");%图像标题
            xlabel(app.UIAxes,"波长\lambda(nm)");%横坐标名
            ylabel(app.UIAxes,"辐射密度E(\lambda,T)(W/mm^3)");%纵坐标名

            %绘图
            plot(app.UIAxes,lambda,E_t,'-r');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, '波长\lambda(nm)')
            ylabel(app.UIAxes, '辐射密度E(\lambda,T)(W/mm^3)')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '黑体温度';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.ExpPlot = uibutton(app.UIFigure, 'push');
            app.ExpPlot.ButtonPushedFcn = createCallbackFcn(app, @ExpPlotPushed, true);
            app.ExpPlot.Text = '测量实验曲线';
            app.KLabel = uilabel(app.UIFigure);
            app.KLabel.Text = 'K';
            app.TheoPlot = uibutton(app.UIFigure, 'push');
            app.TheoPlot.ButtonPushedFcn = createCallbackFcn(app, @TheoPlotButtonPushed, true);
            app.TheoPlot.Text = '理论曲线';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end